classdef sesup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        PanelGridLayout                 matlab.ui.container.GridLayout
        FIGURELabel                     matlab.ui.control.Label
        PlotPanel                       matlab.ui.container.Panel
        SimulationPanel                 matlab.ui.container.Panel
        SimulationGridLayout            matlab.ui.container.GridLayout
        XDKFNmaxgammaEditField          matlab.ui.control.NumericEditField
        XDKFNmaxgammaEditFieldLabel     matlab.ui.control.Label
        SDKFweightEditField             matlab.ui.control.EditField
        SDKFweightEditFieldLabel        matlab.ui.control.Label
        InputdeterministicEditField     matlab.ui.control.EditField
        InputdeterministicEditFieldLabel  matlab.ui.control.Label
        EditSystemButton                matlab.ui.control.Button
        AddSystemButton                 matlab.ui.control.Button
        HinfgammaEditField              matlab.ui.control.EditField
        HinfgammaEditFieldLabel         matlab.ui.control.Label
        ExportSimulationDataButton      matlab.ui.control.Button
        SamplenumberEditField           matlab.ui.control.NumericEditField
        SamplenumberEditFieldLabel      matlab.ui.control.Label
        XDKFgammaEditField              matlab.ui.control.EditField
        XDKFgammaEditFieldLabel         matlab.ui.control.Label
        DKFweightEditField              matlab.ui.control.EditField
        DKFweightEditFieldLabel         matlab.ui.control.Label
        SKFparvarEditField              matlab.ui.control.EditField
        SKFparvarEditFieldLabel         matlab.ui.control.Label
        NumberofMCsimulationsEditField  matlab.ui.control.NumericEditField
        NumberofMCsimulationsEditFieldLabel  matlab.ui.control.Label
        RealparameterstestrangesizeEditField  matlab.ui.control.NumericEditField
        RealparameterstestrangesizeEditFieldLabel  matlab.ui.control.Label
        SelectmultipleListBox           matlab.ui.control.ListBox
        SelectmultipleListBoxLabel      matlab.ui.control.Label
        RunSimulationButton             matlab.ui.control.Button
        SystemDropDown                  matlab.ui.control.DropDown
        FigurePanel                     matlab.ui.container.Panel
        FigureGridLayout                matlab.ui.container.GridLayout
        ImportSimulationDataButton      matlab.ui.control.Button
        Element2DropDown_2              matlab.ui.control.DropDown
        Element2DropDown                matlab.ui.control.DropDown
        OpenasFigureButton              matlab.ui.control.Button
        ParDropDown_2                   matlab.ui.control.DropDown
        ParDropDown_2Label              matlab.ui.control.Label
        ElementDropDown_2               matlab.ui.control.DropDown
        ElementDropDown_2Label          matlab.ui.control.Label
        ScaleXDropDown_2                matlab.ui.control.DropDown
        ScaleXDropDown_2Label           matlab.ui.control.Label
        ScaleYDropDown_2                matlab.ui.control.DropDown
        ScaleYDropDown_2Label           matlab.ui.control.Label
        TypeDropDown_2                  matlab.ui.control.DropDown
        TypeDropDown_2Label             matlab.ui.control.Label
        ParDropDown                     matlab.ui.control.DropDown
        ParDropDownLabel                matlab.ui.control.Label
        ElementDropDown                 matlab.ui.control.DropDown
        ElementDropDownLabel            matlab.ui.control.Label
        ScaleXDropDown                  matlab.ui.control.DropDown
        ScaleXDropDownLabel             matlab.ui.control.Label
        ScaleYDropDown                  matlab.ui.control.DropDown
        ScaleYDropDownLabel             matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        FigureSelectmultipleListBox     matlab.ui.control.ListBox
        SelectmultipleListBox_2Label    matlab.ui.control.Label
        Plot2Switch                     matlab.ui.control.Switch
        Plot2Label                      matlab.ui.control.Label
        Plot1Label                      matlab.ui.control.Label
        SIMULATIONLabel                 matlab.ui.control.Label
    end

    
    properties (Access = public)
        SysNames % Names of defined systems for testing
        SysOpt % System options
        SysDef % System definitions
        
        ParReal % Vector of real parameters to simulate
        ExpNum % Number of monte carlo simulations
        
        nx % State vector size
        ny % Measurements vector size
        nth % Number of parameters
        nu % Input vecotr size
        dfddx % System matrix
        dgdx % Output matrix
        dfddw % Noise matrix
        Q % Process noise covariance
        R % Meas. noise covariance
        fd % System propagation function
        g % Output equation
        dfddth
        ddfddxdth
        dgde 
        dgdth 
        ddgdxdth
        x0_sim % Initial state simulation value
        th0 % Mean parameter value
        x0 % Initial state value
        P0 % Initial estimation error covariance value
        y0 % Initial output value
        thBounds % Parameter simulation bounds
        Tfinal % final simulation time
        
        KfTab % Table of simulated algorithms
        nf
        
        XdkfMaxGamma % Maximum gamma for XDKF objectives normalization
        
        irmse_est
        rmse_est
        mad_est
        x_mu
        x_est_mu
        s_est_mu
        Kx_mu
        Px_mu
        t
        u
        
        Kth_mu
        Ps_mu
        Psx_mu
        
    end
    
    properties (Access = private)
        DialogApp   % Dialog box app
        RootFolder
        PlotReady = false
        SystemLoaded = false
    end
    
    methods (Access = private)
        
        function createKfTab(app)
            
            tab = table([],[],[],'VariableNames',{'FilterName','ConfigId','Par'});
            KfList = app.SelectmultipleListBox.Value;
            
            if any(strcmp("Optimal KF",KfList))
                tab = [tab; {"Optimal KF",1,0}];
            end
            if any(strcmp("KF",KfList))
                tab = [tab; {"KF",1,0}];
            end
            if any(strcmp("SKF",KfList))
                ParVec = evalParKf(app,'SKF');
                for i = 1 : numel(ParVec)
                    tab = [tab; {"SKF", i, ParVec(i)}];
                end
            end
            if any(strcmp("DKF",KfList))
                ParVec = evalParKf(app,'DKF');
                for i = 1 : numel(ParVec)
                    tab = [tab; {"DKF", i, ParVec(i)}];
                end
            end
            if any(strcmp("SDKF",KfList))
                ParVec = evalParKf(app,'SDKF');
                for i = 1 : numel(ParVec)
                    tab = [tab; {"SDKF", i, ParVec(i)}];
                end
            end
            if any(strcmp("XDKF",KfList))
                ParVec = evalParKf(app,'XDKF');
                for i = 1 : numel(ParVec)
                    tab = [tab; {"XDKF", i, ParVec(i)}];
                end
            end
            if any(strcmp("XDKF-Z",KfList))
                ParVec = evalParKf(app,'XDKF-Z');
                for i = 1 : numel(ParVec)
                    tab = [tab; {"XDKF-Z", i, ParVec(i)}];
                end
            end
            if any(strcmp("EXDKF-Z",KfList))
                ParVec = evalParKf(app,'EXDKF-Z');
                for i = 1 : numel(ParVec)
                    tab = [tab; {"EXDKF-Z", i, ParVec(i)}];
                end
            end
            if any(strcmp("EXDKF-ZN",KfList))
                ParVec = evalParKf(app,'EXDKF-ZN');
                for i = 1 : numel(ParVec)
                    tab = [tab; {"EXDKF-ZN", i, ParVec(i)}];
                end
            end
            if any(strcmp("XDKF-N",KfList))
                ParVec = evalParKf(app,'XDKF-N');
                for i = 1 : numel(ParVec)
                    tab = [tab; {"XDKF-N", i, ParVec(i)}];
                end
            end
            if any(strcmp("XDKF-ZN",KfList))
                ParVec = evalParKf(app,'XDKF-ZN');
                for i = 1 : numel(ParVec)
                    tab = [tab; {"XDKF-ZN", i, ParVec(i)}];
                end
            end
            if any(strcmp("XDKF-S",KfList))
                ParVec = evalParKf(app,'XDKF-S');
                for i = 1 : numel(ParVec)
                    tab = [tab; {"XDKF-S", i, ParVec(i)}];
                end
            end
            if any(strcmp("XDKF-I",KfList))
                ParVec = evalParKf(app,'XDKF-I');
                for i = 1 : numel(ParVec)
                    tab = [tab; {"XDKF-I", i, ParVec(i)}];
                end
            end
            if any(strcmp("Hinf",KfList))
                ParVec = evalParKf(app,'Hinf');
                for i = 1 : numel(ParVec)
                    tab = [tab; {"Hinf", i, ParVec(i)}];
                end
            end
            
            tab = addvars(tab,tab.FilterName,'NewVariableNames',{'FullName'});
            for i = 1 : size(tab,1)
                if sum(strcmp(tab.FilterName,tab.FilterName(i)))>1 ...
                        || contains(tab.FilterName(i),'DKF')
                    AppendId = strcat(" (",num2str(tab.Par(i),4),")");
                else
                    AppendId = "";
                end
                tab.FullName(i) = strcat(tab.FullName(i),AppendId);
            end
            
            app.KfTab = tab;
            app.nf = size(tab,1);
        end
        
        
        function val = evalParKf(app,KfName)
            
            switch KfName
                case 'SKF'
                    val = eval(app.SKFparvarEditField.Value);
                case {'DKF'}
                    val = eval(app.DKFweightEditField.Value);
                case {'SDKF'}
                    val = eval(app.SDKFweightEditField.Value);
                case {'XDKF','EXDKF-Z','EXDKF-ZN','XDKF-Z','XDKF-N','XDKF-ZN','XDKF-I','XDKF-S'}
                    val = eval(app.XDKFgammaEditField.Value);
                case 'Hinf'
                    val = eval(app.HinfgammaEditField.Value);
                otherwise
                    error('No value found.');
            end
        end
        
        function initialize(app)
            
            % Parameters
            app.ParReal = linspace(app.thBounds(1),app.thBounds(2),app.RealparameterstestrangesizeEditField.Value);
            nthr = numel(app.ParReal);
            
            % Experiments
            app.ExpNum = app.NumberofMCsimulationsEditField.Value;
            
            % Create table of selected algorithms
            createKfTab(app);
            
            % Max Gamma parameter
            app.XdkfMaxGamma = app.XDKFNmaxgammaEditField.Value;
            
            % Max time (Number of samples)
            nt = app.SamplenumberEditField.Value;
            app.t = 0:nt-1;
            
            % Input
            try
                uValue = eval(app.InputdeterministicEditField.Value);
                if isa(uValue,'numeric') ...
                        && size(uValue,1) == app.nu
                    if size(uValue,2) < nt
                        uValue = repmat(uValue,1,ceil(nt/size(uValue,2)));
                    end
                    app.u = uValue;
                else
                    app.u = zeros(max(app.nu,1),nt);
                    app.InputdeterministicEditField.Value = sprintf('zeros(%d,nt)',max(app.nu,1));
                end
            catch
                app.u = zeros(max(app.nu,1),nt);
                app.InputdeterministicEditField.Value = sprintf('zeros(%d,%d)',max(app.nu,1),nt);
            end
            
            % Init statistics data vectors
            app.rmse_est = zeros(app.nx,nt,app.nf,nthr);
            app.irmse_est = zeros(app.nx,nt,app.nf,nthr);
            app.mad_est = zeros(app.nx,nt,app.nf,nthr);
            
            % Init Mean reponse
            app.x_mu = zeros(app.nx,nt,nthr);
            app.x_est_mu = zeros(app.nx,nt,app.nf,nthr);
            app.s_est_mu = zeros(app.nx,app.nth,nt,app.nf,nthr);
            app.Kx_mu = zeros(app.nx,app.ny,nt,app.nf,nthr);
            app.Px_mu = zeros(app.nx,app.nx,nt,app.nf,nthr);
            % Mean response for parameter related data of the first parameter
            app.Kth_mu = zeros(app.nx,app.ny,nt,app.nf,nthr);
            app.Ps_mu = zeros(app.nx,app.nx,nt,app.nf,nthr);
            app.Psx_mu = zeros(app.nx,app.nx,nt,app.nf,nthr);
            
        end
        
        function simulate(app)
            
            [app.irmse_est,app.rmse_est,app.mad_est,app.x_mu,app.x_est_mu,app.Kx_mu,app.Px_mu,app.Kth_mu,app.s_est_mu,app.Ps_mu,app.Psx_mu] = sesupsimulate(app);
            
        end
        
        
        function showResults(app)
            
            app.FigureSelectmultipleListBox.ItemsData = 1:app.nf;
            app.FigureSelectmultipleListBox.Items = app.KfTab.FullName';
            app.FigureSelectmultipleListBox.Value = 1:app.nf;
            
            app.ElementDropDown.Items     = sprintfc('%d',(1:app.nx)');
            app.ElementDropDown.ItemsData = 1:app.nx;
            app.ElementDropDown_2.Items = sprintfc('%d',(1:app.nx)');
            app.ElementDropDown_2.ItemsData = 1:app.nx;
            
            app.ParDropDown.Items     = [{'avg'},sprintfc('%.4g',app.ParReal)];
            app.ParDropDown.ItemsData = [-1,1:numel(app.ParReal)];
            app.ParDropDown_2.Items = ['avg',sprintfc('%.4g',app.ParReal)];
            app.ParDropDown_2.ItemsData = [-1,1:numel(app.ParReal)];
            
            updateFigure(app)
            
        end
        
        function updateFigure(app)
            
            if app.PlotReady
                
                validKfId = app.FigureSelectmultipleListBox.Value;
                plot_opt = {'linewidth',2};
                
                % Plot 1 Prop
                xn1 = [app.ElementDropDown.Value,app.Element2DropDown.Value];
                thn1 = app.ParDropDown.Value;
                Type1 = app.TypeDropDown.Value;
                YScale1 = app.ScaleYDropDown.Value;
                XScale1 = app.ScaleXDropDown.Value;
                
                % Plot 2 Prop
                xn2 = [app.ElementDropDown_2.Value,app.Element2DropDown_2.Value];
                thn2 = app.ParDropDown_2.Value;
                Type2 = app.TypeDropDown_2.Value;
                YScale2 = app.ScaleYDropDown_2.Value;
                XScale2 = app.ScaleXDropDown_2.Value;
                
                if str2double(app.Plot2Switch.Value)>0.1
                    TileLayout = tiledlayout(app.PlotPanel,2,1);
                    % Plot 1
                    ax1 = nexttile(TileLayout);
                    cla(ax1);
                    hold(ax1,"on");
                    plotResults(app,ax1,validKfId,xn1,thn1,Type1,YScale1,XScale1,plot_opt)
                    hold(ax1,"off");
                    % Plot 2
                    ax2 = nexttile(TileLayout);
                    cla(ax2);
                    hold(ax2,"on");
                    plotResults(app,ax2,validKfId,xn2,thn2,Type2,YScale2,XScale2,plot_opt)
                    hold(ax2,"off");
                else
                    TileLayout = tiledlayout(app.PlotPanel,1,1);
                    % Plot 1
                    ax1 = nexttile(TileLayout);
                    cla(ax1);
                    hold(ax1,"on");
                    plotResults(app,ax1,validKfId,xn1,thn1,Type1,YScale1,XScale1,plot_opt)
                    hold(ax1,"off");
                end
                
            end
            
        end
        
        function plotResults(app,ax,validKfId,xn,thn,Type,YScale,XScale,plot_opt)
            
            sesupPlotResults(app,ax,validKfId,xn,thn,Type,YScale,XScale,plot_opt);
        end
        
        
        function exportSimulationData(app)
            
            [file,path] = uiputfile('*.mat');
            if isequal(file,0) || isequal(path,0)
                % Canceled dialog
            else
                DefId = app.SystemDropDown.Value;
                sysdef = app.SysDef{DefId};
                sysopt = app.SysOpt{DefId};
                
                SavePropName = ["KfTab", "ParReal", "ExpNum", "irmse_est", "rmse_est", "mad_est", "x_mu", "x_est_mu", "Kx_mu", "Px_mu", "Kth_mu","s_est_mu", "Ps_mu", "Psx_mu", "t"];
                data = struct;
                for i = 1:numel(SavePropName)
                    data.(SavePropName(i)) = app.(SavePropName(i));
                end
                savedate = datetime;
                save(fullfile(path,file),"data","savedate","sysdef","sysopt");
            end
            
        end
        
        function openCurrentFigure(app)
            
            if app.PlotReady
                
                validKfId = app.FigureSelectmultipleListBox.Value;
                plot_opt = {'linewidth',2};
                
                % Plot 1
                xn1 = [app.ElementDropDown.Value,1];
                thn1 = app.ParDropDown.Value;
                Type1 = app.TypeDropDown.Value;
                YScale1 = app.ScaleYDropDown.Value;
                XScale1 = app.ScaleXDropDown.Value;
                
                xn2 = [app.ElementDropDown_2.Value,1];
                thn2 = app.ParDropDown_2.Value;
                Type2 = app.TypeDropDown_2.Value;
                YScale2 = app.ScaleYDropDown_2.Value;
                XScale2 = app.ScaleXDropDown_2.Value;
                
                figure;
                
                if str2double(app.Plot2Switch.Value)>0.1
                    tl = tiledlayout(2,1);
                    tl.TileSpacing = 'compact';
                    % subplots
                    ax1 = nexttile;
                    hold(ax1,"on");
                    plotResults(app,ax1,validKfId,xn1,thn1,Type1,YScale1,XScale1,plot_opt)
                    hold(ax1,"off");
                    ax2 = nexttile;
                    hold(ax2,"on");
                    plotResults(app,ax2,validKfId,xn2,thn2,Type2,YScale2,XScale2,plot_opt)
                    hold(ax2,"off");
                else
                    % single plot
                    ax1 = gca;
                    hold(ax1,"on");
                    plotResults(app,ax1,validKfId,xn1,thn1,Type1,YScale1,XScale1,plot_opt)
                    hold(ax1,"off");
                end
                
            end
            
        end
        
        function loadSysFile(app,FileName)
            
            if isempty(app.SysDef)
                sysid = 1;
            else
                sysid = numel(app.SysDef)+1;
            end
            
            S = load(FileName);
            if isfield(S,'sys')
                app.SysDef{sysid} = S.sys;
            else
                error('File %s must include system definition (''sys'' variable).',FileName);
            end
            
            if isfield(S,'opt')
                app.SysOpt{sysid} = S.opt;
            else
                app.SysOpt{sysid} = struct;
            end
            
            if isfield(S,'name')
                app.SysNames{sysid} = S.name;
            else
                app.SysNames{sysid} = sprintf('System%d',sysid);
            end
        end
        
        function loadSysDef(app)
            
            DefId = app.SystemDropDown.Value;
            
            if numel(app.SysNames)<DefId
                error('Cannot load system. Invalid system id.');
            else
                sys = app.SysDef{DefId};
                % System dimensions
                app.nx = sys.nx;
                app.nth = sys.nth;
                app.ny = sys.ny;
                app.nu = sys.nu;
                % Initialize casadi
                if ~exist('casadi.SX','class')
                    error('Model compilation requires the CASADI toolbox, please install it from casadi.org. The code was tested against version 3.0.');
                end
                import casadi.*
                st = SX.sym('t',1);
                x = SX.sym('x',app.nx);
                u  = SX.sym('u',max(app.nu,1));
                th = SX.sym('th',app.nth); %obj.nth);
                w = SX.sym('w',app.nx);
                e = SX.sym('e',app.ny);
                
                inputs      = {st,x,u,th,w};
                input_names = {'t','x','u','th','w'};
                ix          = 2;
                args        = {st,x,u,th,w};
                inputs_y = [inputs(1:end-1),{e}];
                input_names_y = [input_names(1:end-1),{'e'}];
                args_y = [inputs(1:end-1),{e}];
                
                switch class(sys)
                    case {'nlcm','som2nlcm'}
                        opt = app.SysOpt{DefId};
                        if isempty(app.SysOpt{DefId}) || ~isfield(opt,'Ts')
                            opt.Ts = 1;
                            opt.SubSamples = 1;
                            warndlg('No sampling period (opt.Ts) definition has been found. Setting Ts = 1.');
                        else
                            if ~isfield(opt,'SubSamples')
                                opt.SubSamples = 1;
                            end
                        end
                        % Fixed discretiation method
%                         opt.DiscretizationMethod = 'euler';
                        opt.DiscretizationMethod = 'runge-kutta';
                        % System propagation equation
                        app.fd = discretizeStochastic(@sys.fc,inputs,input_names,ix,opt);
                        % Measurement equation (add stochastic term)
                        app.g = casadi.Function('g',inputs_y,{sys.g(args_y{1:end-1})+e},input_names_y,{'g'});
                        
                    case 'struct'
                        % System propagation equation
                        app.fd = casadi.Function('fd',inputs,{sys.fd(args{:})},input_names,{'fd'});
                        % Measurement equation
                        app.g = casadi.Function('g',inputs_y,{sys.g(args_y{:})},input_names_y,{'g'});
                        
                    otherwise
                        error('%s class models are not supported.',class(app.SysDef{DefId}));
                end
                
                % Jacobians & other differential forms
                fce  = jacobian(app.fd(inputs{:}),x);
                app.dfddx = casadi.Function('dfddx',inputs,{fce},input_names,{'dfddx'});
                fce  = jacobian(app.fd(inputs{:}),w);
                app.dfddw = casadi.Function('dfddw',inputs,{fce},input_names,{'dfddw'});
                fce  = jacobian(app.fd(inputs{:}),th);
                app.dfddth = casadi.Function('dfddth',inputs,{fce},input_names,{'dfddth'});
                fce  = jacobian(app.dfddx(inputs{:}),th);
                app.ddfddxdth = casadi.Function('ddfddxdth',inputs,{fce},input_names,{'ddfddxdth'});
                fce  = jacobian(app.g(inputs_y{:}),x);
                app.dgdx = casadi.Function('dgdx',inputs_y,{fce},input_names_y,{'dgdx'});
                fce  = jacobian(app.g(inputs_y{:}),e);
                app.dgde = casadi.Function('dgde',inputs_y,{fce},input_names_y,{'dgde'});
                fce  = jacobian(app.g(inputs_y{:}),th);
                app.dgdth = casadi.Function('dgdth',inputs_y,{fce},input_names_y,{'dgdth'});
                fce  = jacobian(app.dgdx(inputs_y{:}),th);
                app.ddgdxdth = casadi.Function('ddgdxdth',inputs_y,{fce},input_names_y,{'ddgdxdth'});
                
                % Other Settings
                if isempty(app.SysOpt{DefId}) || ~isfield(app.SysOpt{DefId},'Q')
                    % Noise covariances
                    opt.R = eye(app.ny);
                    opt.Q = eye(app.nx);
                    % Initialization
                    opt.x0_sim = zeros(app.nx,1);
                    opt.x0 = zeros(app.nx,1);
                    opt.P0 = eye(app.nx);
                    opt.y0 = zeros(app.ny,1);
                    % Parameter bounds
                    opt.thBounds = ones(app.nth,1)*[-1 1];
                    % Parameter mean
                    opt.th0 = zeros(app.nth,1);
                    app.SysOpt{DefId} = opt;
                end
                % Load Other Settings
                opt = app.SysOpt{DefId};
                % Noise covariances
                app.R = opt.R;
                app.Q = opt.Q;
                % Initialization
                app.x0_sim = opt.x0_sim;
                app.x0 = opt.x0;
                app.P0 = opt.P0;
                app.y0 = opt.y0;
                % Parameter bounds
                app.thBounds = opt.thBounds;
                % Parameter mean
                app.th0 = opt.th0;
                
                
            end
        end
        
        function genModelCode(app)
            
            opts = struct('mex', true);
            C = casadi.CodeGenerator('genmod.c',opts);
            C.add(app.fd);
            C.add(app.dfddx);
            C.add(app.dfddw);
            C.add(app.dfddth);
            C.add(app.ddfddxdth);
            C.add(app.g);
            C.add(app.dgdx);
            C.add(app.dgde);
            C.add(app.dgdth);
            C.add(app.ddgdxdth);
            C.generate();
            
            try
                mex -setup C
                mex genmod.c -largeArrayDims
            catch ME
                warning('Model code generation resulted in error. Check if compatible C/C++ compiler is installed and configured.');
                warning('MEX file for the default system will be used.');
                
                warndlg('Model code generation resulted in error. Check if compatible C/C++ compiler is installed and configured. MEX file for the default system will be used instead.','Code generation error');
            end
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,"center");
            stck = dbstack("-completenames");
%             [app.RootFolder,~,~] = fileparts(mfilename('fullpath'));
            app.RootFolder = fileparts(stck(1).file);
            app.loadSysFile(fullfile(app.RootFolder,'model','sayed.mat'));
            app.SystemDropDown.Items = app.SysNames;
            app.SystemDropDown.ItemsData = 1:numel(app.SysNames);
            
            addpath(fullfile(app.RootFolder,'funs'));
            addpath(fullfile(app.RootFolder,'filter'));
            
            try
                % Added for GUI compatibility with R2020b
                app.PanelGridLayout.BackgroundColor = app.UIFigure.Color;
            catch ME
                % Matlab version older than R2020b
            end
            
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            
            ProgressDialog = uiprogressdlg(app.UIFigure,'Title','Preparing simulation',...
                'Message','Wait');
            % Handle response
            if ~app.SystemLoaded
                % Close current parallel pool
                try
                    ProgressDialog.Value = 0.1;
                    ProgressDialog.Message = 'Deleting current parallel pool';
                    delete(gcp);
                catch
                    warning('Trying to delete current parallel pool. Parallel Computing Toolbox is not available.')
                end
                ProgressDialog.Value = 0.25;
                ProgressDialog.Message = 'Loading system definition';
                % Define System
                loadSysDef(app);
                
                ProgressDialog.Value = 0.5;
                ProgressDialog.Message = 'Generating model code';
                % Generate code
                genModelCode(app);
                
                app.SystemLoaded = true;
            else
                ProgressDialog.Value = 0.5;
                ProgressDialog.Message = 'Loading system definition';
                % Define System
                loadSysDef(app);
            end
            
            ProgressDialog.Value = 0.75;
            ProgressDialog.Message = 'Initializing data';
            % Initialize
            initialize(app);
            app.PlotReady = true;
            
            close(ProgressDialog);
            % Run simulations
            simulate(app);
            
            % Show results
            showResults(app);
            
            
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            if ~isempty(strfind(app.TypeDropDown.Value,"Gain"))
                app.Element2DropDown.Items     = sprintfc('%d',(1:app.ny)');
                app.Element2DropDown.ItemsData = 1:app.ny;
            elseif ~isempty(strfind(app.TypeDropDown.Value,"Cov"))
                app.Element2DropDown.Items     = sprintfc('%d',(1:app.nx)');
                app.Element2DropDown.ItemsData = 1:app.nx;
            else
                app.Element2DropDown.Items     = sprintfc('%d',1);
                app.Element2DropDown.ItemsData = 1;
            end
            
            updateFigure(app);
            
        end

        % Value changed function: ElementDropDown
        function ElementDropDownValueChanged(app, event)
            updateFigure(app);
            
        end

        % Value changed function: ParDropDown
        function ParDropDownValueChanged(app, event)
            updateFigure(app);
            
        end

        % Value changed function: ScaleYDropDown
        function ScaleYDropDownValueChanged(app, event)
            updateFigure(app);
            
        end

        % Value changed function: ScaleXDropDown
        function ScaleXDropDownValueChanged(app, event)
            updateFigure(app);
            
        end

        % Value changed function: FigureSelectmultipleListBox
        function FigureSelectmultipleListBoxValueChanged(app, event)
            updateFigure(app);
            
        end

        % Value changed function: Plot2Switch
        function Plot2SwitchValueChanged(app, event)
            TypeDropDown_2ValueChanged(app);
            
        end

        % Value changed function: TypeDropDown_2
        function TypeDropDown_2ValueChanged(app, event)
            if ~isempty(strfind(app.TypeDropDown_2.Value,"Gain"))
                app.Element2DropDown_2.Items     = sprintfc('%d',(1:app.ny)');
                app.Element2DropDown_2.ItemsData = 1:app.ny;
            elseif ~isempty(strfind(app.TypeDropDown_2.Value,"Cov"))
                app.Element2DropDown_2.Items     = sprintfc('%d',(1:app.nx)');
                app.Element2DropDown_2.ItemsData = 1:app.nx;
            else
                app.Element2DropDown_2.Items     = sprintfc('%d',1);
                app.Element2DropDown_2.ItemsData = 1;
            end
            
            updateFigure(app);
            
        end

        % Value changed function: ElementDropDown_2
        function ElementDropDown_2ValueChanged(app, event)
            updateFigure(app);
            
        end

        % Value changed function: ParDropDown_2
        function ParDropDown_2ValueChanged(app, event)
            updateFigure(app);
            
        end

        % Value changed function: ScaleYDropDown_2
        function ScaleYDropDown_2ValueChanged(app, event)
            updateFigure(app);
            
        end

        % Value changed function: ScaleXDropDown_2
        function ScaleXDropDown_2ValueChanged(app, event)
            updateFigure(app);
            
        end

        % Button pushed function: ExportSimulationDataButton
        function ExportSimulationDataButtonPushed(app, event)
            exportSimulationData(app);
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            MsgName = 'Testing State Estimation for Systems with Uncertain Parameters (SESUP)';
            
            MsgCiteEx = 'A. H. Sayed, "A framework for state-space estimation with uncertain models," IEEE Transactions on Automatic Control, vol. 46, no. 7, pp. 998–1013, Jul. 2001.';
            
            MsgCiteAlgSkf = 'A. H. Jazwinski, Stochastic Processes and Filtering Theory, vol. 64. Burlington: Academic Press, 1970.';
            MsgCiteAlgHinf = 'F. L. Lewis, L. Xie, and D. Popa, Optimal and robust estimation: with an introduction to stochastic control theory, 2nd ed., vol. 26. Boca Raton: CRC, 2008.';
            MsgCiteAlgDkf = 'C. D. Karlgaard and H. Shen, “Desensitised Kalman filtering,” IET Radar, Sonar &amp; Navigation, vol. 7, no. 1, pp. 2–9, Jan. 2013.';
            MsgCiteAlgSdkf = 'J. Tabacek and V. Havlena, "Desensitized Filtering for Systems with Uncertain Parameters and Noise Correlation," in 2018 26th Mediterranean Conference on Control and Automation (MED), 2018.';
            MsgCiteAlgXDKF = 'J. Tabacek and V. Havlena, "Reduction of stochastic prediction error sensitivity to parameters in Kalman filter," Journal of Franklin Institute, 2022';
            
            MsgAuthor = 'Jaroslav Tabacek (tabacjar@fel.cvut.cz)';
            
            AboutMessage = sprintf(['%s\n\n Used systems:\n\n %s\n\n Used algorithms:\n\n' ...
                'SKF:\n%s\n ' ...
                'Hinf:\n%s\n ' ...
                'DKF:\n%s\n ' ...
                'SDKF:\n%s\n ' ...
                'XDKF:\n%s\n ' ...
                '\n\n Copyright 2021 %s\n\n'],...
                MsgName, MsgCiteEx, MsgCiteAlgSkf, MsgCiteAlgHinf, MsgCiteAlgDkf, MsgCiteAlgSdkf, MsgCiteAlgXDKF, MsgAuthor);
            
            uiconfirm(app.UIFigure,AboutMessage,'About','Options',{'OK'},'Icon','info');
            
        end

        % Button pushed function: OpenasFigureButton
        function OpenasFigureButtonPushed(app, event)
            openCurrentFigure(app);
        end

        % Button pushed function: ImportSimulationDataButton
        function ImportSimulationDataButtonPushed(app, event)
            
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
                % Cancelled
            else
                S = load(fullfile(path, file));
                app.KfTab = S.data.KfTab;
                app.ParReal = S.data.ParReal;
                app.t = S.data.t;
                app.x_mu = S.data.x_mu;
                app.x_est_mu = S.data.x_est_mu;
                app.Kx_mu = S.data.Kx_mu;
                app.irmse_est = S.data.irmse_est;
                app.rmse_est = S.data.rmse_est;
                app.nx = size(S.data.x_mu,1);
                
                try
                    app.Px_mu = S.data.Px_mu;
                    app.Ps_mu = S.data.Ps_mu;
                    app.Psx_mu = S.data.Psx_mu;
                    app.Kth_mu = S.data.Kth_mu;
                    app.mad_est = S.data.mad_est;
                    app.s_est_mu = S.data.s_est_mu;
                catch ME
                    warning('Some data are unavailable. Setting unavailable data zero.')
                    sz = size(app.x_mu,1);
                    app.Px_mu = zeros([sz(1),sz(1),sz(2:end)]);
                    app.Ps_mu = zeros([sz(1),sz(1),sz(2:end)]);
                    app.Psx_mu = zeros([sz(1),sz(1),sz(2:end)]);
                    app.Kth_mu = app.Kx_mu*0;
                    app.mad_est = app.irmse_est*0;
                    app.s_est_mu = app.x_est_mu*0;
                end
                
                app.nf = size(app.KfTab,1);
                app.nx = size(app.Kx_mu,1);
                app.ny = size(app.Kx_mu,2);
                app.Tfinal = app.t(end);
                app.PlotReady = true;
                % Show results
                showResults(app);
                
                app.UIFigure.Visible = true;
            end
            
            
        end

        % Button pushed function: AddSystemButton
        function AddSystemButtonPushed(app, event)
            
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
                % Cancelled
            else
                % S = load(fullfile(path, file));
                app.loadSysFile(fullfile(path, file));
                app.SystemLoaded = true;
            end
            app.SystemDropDown.Items = app.SysNames;
            app.SystemDropDown.ItemsData = 1:numel(app.SysNames);
            
        end

        % Value changed function: SystemDropDown
        function SystemDropDownValueChanged(app, event)
            ProgressDialog = uiprogressdlg(app.UIFigure,'Title','Loading system',...
                'Message','Handling pool');
            ProgressDialog.Value = 0.25;
            try
                % Delete pool, if one exists.
                p = gcp('nocreate');
                if ~isempty(p)
                    delete(gcp);
                end
            catch
                warning('Trying to delete current parallel pool. Parallel Computing Toolbox is not available.')
            end
            % Load def
            ProgressDialog.Value = 0.5;
            ProgressDialog.Message = 'Loading system definition';
            loadSysDef(app);
            % Generate
            ProgressDialog.Value = 0.75;
            ProgressDialog.Message = 'Generating code';
            genModelCode(app);
            
        end

        % Button pushed function: EditSystemButton
        function EditSystemButtonPushed(app, event)
            if app.SystemLoaded
                loadSysDef(app);
            else
                ProgressDialog = uiprogressdlg(app.UIFigure,'Title','Loading system',...
                    'Message','Handling pool');
                ProgressDialog.Value = 0.25;
                try
                    % Delete pool, if one exists.
                    p = gcp('nocreate');
                    if ~isempty(p)
                        delete(gcp);
                    end
                catch
                    warning('Trying to delete current parallel pool. Parallel Computing Toolbox is not available.')
                end
                % Load def
                ProgressDialog.Value = 0.5;
                ProgressDialog.Message = 'Loading system definition';
                loadSysDef(app);
                % Generate
                ProgressDialog.Value = 0.75;
                ProgressDialog.Message = 'Generating code';
                genModelCode(app);
                
                app.SystemLoaded = true;
            end
            
            DefId = app.SystemDropDown.Value;
            
            % Disable options while dialog is open
            app.EditSystemButton.Enable = 'off';
            app.AddSystemButton.Enable = 'off';
            app.SystemDropDown.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.DialogApp = sesupOptions(app, app.SysDef{DefId}, app.SysOpt{DefId});
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DialogApp);
            delete(app);
            
        end

        % Value changed function: Element2DropDown
        function Element2DropDownValueChanged(app, event)
            updateFigure(app);
            
        end

        % Value changed function: Element2DropDown_2
        function Element2DropDown_2ValueChanged(app, event)
            updateFigure(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SESUP';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.PanelGridLayout = uigridlayout(app.UIFigure);
            app.SIMULATIONLabel = uilabel(app.PanelGridLayout);
            app.SIMULATIONLabel.Text = 'SIMULATION';
            app.FigurePanel = uipanel(app.PanelGridLayout);
            app.FigureGridLayout = uigridlayout(app.FigurePanel);
            app.Plot1Label = uilabel(app.FigureGridLayout);
            app.Plot1Label.Text = 'Plot 1';
            app.Plot2Label = uilabel(app.FigureGridLayout);
            app.Plot2Label.Text = 'Plot 2';
            app.Plot2Switch = uiswitch(app.FigureGridLayout, 'slider');
            app.Plot2Switch.Items = {'OFF', 'ON'};
            app.Plot2Switch.ItemsData = {'0', '1'};
            app.Plot2Switch.ValueChangedFcn = createCallbackFcn(app, @Plot2SwitchValueChanged, true);
            app.Plot2Switch.Value = '0';
            app.SelectmultipleListBox_2Label = uilabel(app.FigureGridLayout);
            app.SelectmultipleListBox_2Label.Text = {'Select '; 'multiple'};
            app.FigureSelectmultipleListBox = uilistbox(app.FigureGridLayout);
            app.FigureSelectmultipleListBox.Items = {'Optimal KF'};
            app.FigureSelectmultipleListBox.ItemsData = {'1'};
            app.FigureSelectmultipleListBox.Multiselect = 'on';
            app.FigureSelectmultipleListBox.ValueChangedFcn = createCallbackFcn(app, @FigureSelectmultipleListBoxValueChanged, true);
            app.FigureSelectmultipleListBox.Tooltip = {'Select the results for plotting'};
            app.FigureSelectmultipleListBox.Value = {'1'};
            app.TypeDropDownLabel = uilabel(app.FigureGridLayout);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.FigureGridLayout);
            app.TypeDropDown.Items = {'State', 'Sensitivity', 'Instant RMSE', 'RMSE', 'MAD', 'Gain', 'Gain (sensitivity)', '2nd moment (state)', '2nd moment (sensitivity)', '2nd moment (cross)', 'Trace 2nd moment (state)', 'Trace 2nd moment (sensitivity)', 'Norm (sensitivity)', 'Final RMSE', 'Final MAD', 'Final Gain', 'Final Gain (sensitivity)', 'Final 2nd moment (state)', 'Final 2nd moment (sensitivity)', 'Final 2nd moment (cross)', 'Final Trace (state)', 'Final Trace (sensitivity)', 'Final Norm (sensitivity)'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Final RMSE';
            app.ScaleYDropDownLabel = uilabel(app.FigureGridLayout);
            app.ScaleYDropDownLabel.Text = 'Scale Y';
            app.ScaleYDropDown = uidropdown(app.FigureGridLayout);
            app.ScaleYDropDown.Items = {'Default', 'Log'};
            app.ScaleYDropDown.ValueChangedFcn = createCallbackFcn(app, @ScaleYDropDownValueChanged, true);
            app.ScaleYDropDown.Tooltip = {'Allows to change the scale of axis. "Log" option sets dB scale 10*log10(Y)'};
            app.ScaleYDropDown.Value = 'Default';
            app.ScaleXDropDownLabel = uilabel(app.FigureGridLayout);
            app.ScaleXDropDownLabel.Text = 'Scale X';
            app.ScaleXDropDown = uidropdown(app.FigureGridLayout);
            app.ScaleXDropDown.Items = {'Default', 'Log'};
            app.ScaleXDropDown.ValueChangedFcn = createCallbackFcn(app, @ScaleXDropDownValueChanged, true);
            app.ScaleXDropDown.Tooltip = {'Allows to change the scale of axis. "Log" option sets base-10 logaritmic scale log10(X)'};
            app.ScaleXDropDown.Value = 'Default';
            app.ElementDropDownLabel = uilabel(app.FigureGridLayout);
            app.ElementDropDownLabel.Text = 'Element';
            app.ElementDropDown = uidropdown(app.FigureGridLayout);
            app.ElementDropDown.Items = {'1', '2'};
            app.ElementDropDown.ItemsData = {'1', '2'};
            app.ElementDropDown.ValueChangedFcn = createCallbackFcn(app, @ElementDropDownValueChanged, true);
            app.ElementDropDown.Tooltip = {'If applicable, select the element of vector/matrix'};
            app.ElementDropDown.Value = '1';
            app.ParDropDownLabel = uilabel(app.FigureGridLayout);
            app.ParDropDownLabel.Text = 'Par.';
            app.ParDropDown = uidropdown(app.FigureGridLayout);
            app.ParDropDown.Items = {'-1', '0', '1'};
            app.ParDropDown.ItemsData = {'1', '2', '3'};
            app.ParDropDown.ValueChangedFcn = createCallbackFcn(app, @ParDropDownValueChanged, true);
            app.ParDropDown.Tooltip = {'If applicable, select the simulation with the particular real parameter'};
            app.ParDropDown.Value = '1';
            app.TypeDropDown_2Label = uilabel(app.FigureGridLayout);
            app.TypeDropDown_2Label.Text = 'Type';
            app.TypeDropDown_2 = uidropdown(app.FigureGridLayout);
            app.TypeDropDown_2.Items = {'State', 'Sensitivity', 'Instant RMSE', 'RMSE', 'MAD', 'Gain', 'Gain (sensitivity)', '2nd moment (state)', '2nd moment (sensitivity)', '2nd moment (cross)', 'Trace 2nd moment (state)', 'Trace 2nd moment (sensitivity)', 'Norm (sensitivity)', 'Final RMSE', 'Final MAD', 'Final Gain', 'Final Gain (sensitivity)', 'Final 2nd moment (state)', 'Final 2nd moment (sensitivity)', 'Final 2nd moment (cross)', 'Final Trace (state)', 'Final Trace (sensitivity)', 'Final Norm (sensitivity)'};
            app.TypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @TypeDropDown_2ValueChanged, true);
            app.TypeDropDown_2.Value = 'Gain';
            app.ScaleYDropDown_2Label = uilabel(app.FigureGridLayout);
            app.ScaleYDropDown_2Label.Text = 'Scale Y';
            app.ScaleYDropDown_2 = uidropdown(app.FigureGridLayout);
            app.ScaleYDropDown_2.Items = {'Default', 'Log'};
            app.ScaleYDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ScaleYDropDown_2ValueChanged, true);
            app.ScaleYDropDown_2.Tooltip = {'Allows to change the scale of axis. "Log" option sets dB scale 10*log10(Y)'};
            app.ScaleYDropDown_2.Value = 'Default';
            app.ScaleXDropDown_2Label = uilabel(app.FigureGridLayout);
            app.ScaleXDropDown_2Label.Text = 'Scale X';
            app.ScaleXDropDown_2 = uidropdown(app.FigureGridLayout);
            app.ScaleXDropDown_2.Items = {'Default', 'Log'};
            app.ScaleXDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ScaleXDropDown_2ValueChanged, true);
            app.ScaleXDropDown_2.Tooltip = {'Allows to change the scale of axis. "Log" option sets base-10 logaritmic scale log10(X)'};
            app.ScaleXDropDown_2.Value = 'Default';
            app.ElementDropDown_2Label = uilabel(app.FigureGridLayout);
            app.ElementDropDown_2Label.Text = 'Element';
            app.ElementDropDown_2 = uidropdown(app.FigureGridLayout);
            app.ElementDropDown_2.Items = {'1', '2'};
            app.ElementDropDown_2.ItemsData = {'1', '2'};
            app.ElementDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ElementDropDown_2ValueChanged, true);
            app.ElementDropDown_2.Tooltip = {'If applicable, select the element of vector/matrix'};
            app.ElementDropDown_2.Value = '1';
            app.ParDropDown_2Label = uilabel(app.FigureGridLayout);
            app.ParDropDown_2Label.Text = 'Par.';
            app.ParDropDown_2 = uidropdown(app.FigureGridLayout);
            app.ParDropDown_2.Items = {'-1', '0', '1'};
            app.ParDropDown_2.ItemsData = {'1', '2', '3'};
            app.ParDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ParDropDown_2ValueChanged, true);
            app.ParDropDown_2.Tooltip = {'If applicable, select the simulation with the particular real parameter'};
            app.ParDropDown_2.Value = '1';
            app.OpenasFigureButton = uibutton(app.FigureGridLayout, 'push');
            app.OpenasFigureButton.ButtonPushedFcn = createCallbackFcn(app, @OpenasFigureButtonPushed, true);
            app.OpenasFigureButton.Tooltip = {'Open the figure as the MATLAB Figure object.'};
            app.OpenasFigureButton.Text = 'Open as Figure';
            app.Element2DropDown = uidropdown(app.FigureGridLayout);
            app.Element2DropDown.Items = {'1', '2'};
            app.Element2DropDown.ItemsData = {'1', '2'};
            app.Element2DropDown.ValueChangedFcn = createCallbackFcn(app, @Element2DropDownValueChanged, true);
            app.Element2DropDown.Value = '1';
            app.Element2DropDown_2 = uidropdown(app.FigureGridLayout);
            app.Element2DropDown_2.Items = {'1', '2'};
            app.Element2DropDown_2.ItemsData = {'1', '2'};
            app.Element2DropDown_2.ValueChangedFcn = createCallbackFcn(app, @Element2DropDown_2ValueChanged, true);
            app.Element2DropDown_2.Value = '1';
            app.ImportSimulationDataButton = uibutton(app.FigureGridLayout, 'push');
            app.ImportSimulationDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSimulationDataButtonPushed, true);
            app.ImportSimulationDataButton.Tooltip = {'Import data exported from previous simulations.'};
            app.ImportSimulationDataButton.Text = 'Import Simulation Data';
            app.SimulationPanel = uipanel(app.PanelGridLayout);
            app.SimulationGridLayout = uigridlayout(app.SimulationPanel);
            app.SystemDropDown = uidropdown(app.SimulationGridLayout);
            app.SystemDropDown.Items = {'Sayed (2001) Framework'};
            app.SystemDropDown.ItemsData = {'1'};
            app.SystemDropDown.ValueChangedFcn = createCallbackFcn(app, @SystemDropDownValueChanged, true);
            app.SystemDropDown.Value = '1';
            app.RunSimulationButton = uibutton(app.SimulationGridLayout, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Tooltip = {'Start Monte Carlo simulations. The simulations can be time-consuming, please be patient.'};
            app.RunSimulationButton.Text = 'Run Simulation';
            app.SelectmultipleListBoxLabel = uilabel(app.SimulationGridLayout);
            app.SelectmultipleListBoxLabel.Text = 'Select multiple';
            app.SelectmultipleListBox = uilistbox(app.SimulationGridLayout);
            app.SelectmultipleListBox.Items = {'Optimal KF', 'KF', 'SKF', 'Hinf', 'DKF', 'SDKF', 'XDKF', 'XDKF-Z', 'EXDKF-Z', 'XDKF-N', 'XDKF-ZN', 'EXDKF-ZN', 'XDKF-S'};
            app.SelectmultipleListBox.Multiselect = 'on';
            app.SelectmultipleListBox.Tooltip = {'Select one or more algorithms for testing.'; 'Use the combination Ctrl+LeftClick to choose multiple.'; 'Warning: the DKF simulations takes significantly more time than others. Choose wisely.'};
            app.SelectmultipleListBox.Value = {'Optimal KF', 'KF', 'XDKF-Z', 'XDKF-ZN'};
            app.RealparameterstestrangesizeEditFieldLabel = uilabel(app.SimulationGridLayout);
            app.RealparameterstestrangesizeEditFieldLabel.Text = {'Real parameters'; 'test range size'};
            app.RealparameterstestrangesizeEditField = uieditfield(app.SimulationGridLayout, 'numeric');
            app.RealparameterstestrangesizeEditField.Limits = [1 100];
            app.RealparameterstestrangesizeEditField.Tooltip = {'Number of evenly spaced values in predefined parameter range. '; 'The range can be changed using Edit button. Separate simulation will be performed for each real parameter. '};
            app.RealparameterstestrangesizeEditField.Value = 5;
            app.NumberofMCsimulationsEditFieldLabel = uilabel(app.SimulationGridLayout);
            app.NumberofMCsimulationsEditFieldLabel.Text = {'Number of MC'; 'simulations'};
            app.NumberofMCsimulationsEditField = uieditfield(app.SimulationGridLayout, 'numeric');
            app.NumberofMCsimulationsEditField.Limits = [1 Inf];
            app.NumberofMCsimulationsEditField.Tooltip = {'Number of Monte Carlo simulations. The results will be averaged.'};
            app.NumberofMCsimulationsEditField.Value = 50;
            app.SKFparvarEditFieldLabel = uilabel(app.SimulationGridLayout);
            app.SKFparvarEditFieldLabel.Text = 'SKF par var';
            app.SKFparvarEditField = uieditfield(app.SimulationGridLayout, 'text');
            app.SKFparvarEditField.Tooltip = {'Single or multiple configurations in vector. Simulation will be done for each value separately.'};
            app.SKFparvarEditField.Value = '1';
            app.DKFweightEditFieldLabel = uilabel(app.SimulationGridLayout);
            app.DKFweightEditFieldLabel.Text = 'DKF weight';
            app.DKFweightEditField = uieditfield(app.SimulationGridLayout, 'text');
            app.DKFweightEditField.Tooltip = {'Single or multiple configurations in vector. Simulation will be done for each value separately.'};
            app.DKFweightEditField.Value = '[0.1 1 9]';
            app.XDKFgammaEditFieldLabel = uilabel(app.SimulationGridLayout);
            app.XDKFgammaEditFieldLabel.Text = 'XDKF gamma';
            app.XDKFgammaEditField = uieditfield(app.SimulationGridLayout, 'text');
            app.XDKFgammaEditField.Tooltip = {'Single or multiple configurations in vector. Simulation will be done for each value separately.'};
            app.XDKFgammaEditField.Value = '[0.1 0.5 0.9]';
            app.SamplenumberEditFieldLabel = uilabel(app.SimulationGridLayout);
            app.SamplenumberEditFieldLabel.Text = 'Sample number';
            app.SamplenumberEditField = uieditfield(app.SimulationGridLayout, 'numeric');
            app.SamplenumberEditField.Limits = [1 Inf];
            app.SamplenumberEditField.Value = 1000;
            app.ExportSimulationDataButton = uibutton(app.SimulationGridLayout, 'push');
            app.ExportSimulationDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSimulationDataButtonPushed, true);
            app.ExportSimulationDataButton.Tooltip = {'Save data in format  which can be later imported to the application and browsed.'};
            app.ExportSimulationDataButton.Text = 'Export Simulation Data';
            app.HinfgammaEditFieldLabel = uilabel(app.SimulationGridLayout);
            app.HinfgammaEditFieldLabel.Text = 'Hinf gamma';
            app.HinfgammaEditField = uieditfield(app.SimulationGridLayout, 'text');
            app.HinfgammaEditField.Tooltip = {'Single or multiple configurations in vector. Simulation will be done for each value separately.'};
            app.HinfgammaEditField.Value = '99';
            app.AddSystemButton = uibutton(app.SimulationGridLayout, 'push');
            app.AddSystemButton.ButtonPushedFcn = createCallbackFcn(app, @AddSystemButtonPushed, true);
            app.AddSystemButton.Tooltip = {'Add custom model for testing. For required format, please see the files in directory /model '};
            app.AddSystemButton.Text = 'Add';
            app.EditSystemButton = uibutton(app.SimulationGridLayout, 'push');
            app.EditSystemButton.ButtonPushedFcn = createCallbackFcn(app, @EditSystemButtonPushed, true);
            app.EditSystemButton.Tooltip = {'See the system definition and modify the configuration'};
            app.EditSystemButton.Text = 'Edit';
            app.InputdeterministicEditFieldLabel = uilabel(app.SimulationGridLayout);
            app.InputdeterministicEditFieldLabel.Text = 'Input (deterministic)';
            app.InputdeterministicEditField = uieditfield(app.SimulationGridLayout, 'text');
            app.InputdeterministicEditField.Value = '1';
            app.SDKFweightEditFieldLabel = uilabel(app.SimulationGridLayout);
            app.SDKFweightEditFieldLabel.Text = 'SDKF weight';
            app.SDKFweightEditField = uieditfield(app.SimulationGridLayout, 'text');
            app.SDKFweightEditField.Tooltip = {'Single or multiple configurations in vector. Simulation will be done for each value separately.'};
            app.SDKFweightEditField.Value = '[0.1 1 9]';
            app.XDKFNmaxgammaEditFieldLabel = uilabel(app.SimulationGridLayout);
            app.XDKFNmaxgammaEditFieldLabel.Text = 'XDKF-N max. gamma';
            app.XDKFNmaxgammaEditField = uieditfield(app.SimulationGridLayout, 'numeric');
            app.XDKFNmaxgammaEditField.Limits = [0 1];
            app.XDKFNmaxgammaEditField.Tooltip = {'Maximum gamma parameter for XDKF objectives normalization'};
            app.XDKFNmaxgammaEditField.Value = 0.99;
            app.PlotPanel = uipanel(app.PanelGridLayout);
            app.FIGURELabel = uilabel(app.PanelGridLayout);
            app.FIGURELabel.Text = 'FIGURE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sesup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end